classdef CBO_Scan_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CBOScanUIFigure                matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        TextArea                       matlab.ui.control.TextArea
        UpdatePlotButton               matlab.ui.control.Button
        OpenVIButton                   matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        ABORTButton                    matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        StartVEditFieldLabel           matlab.ui.control.Label
        StartVEditField                matlab.ui.control.NumericEditField
        EndVEditFieldLabel             matlab.ui.control.Label
        EndVEditField                  matlab.ui.control.NumericEditField
        BasenameEditFieldLabel         matlab.ui.control.Label
        BasenameEditField              matlab.ui.control.EditField
        NumberofPointsYEditFieldLabel  matlab.ui.control.Label
        NumberofPointsYEditField       matlab.ui.control.NumericEditField
        InprogressLampLabel            matlab.ui.control.Label
        InprogressLamp                 matlab.ui.control.Lamp
        AOSignalDropDownLabel          matlab.ui.control.Label
        AOSignalDropDown               matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        CallingApp; % Description
        vi;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SPMUI)
            app.CallingApp = SPMUI;
            index = app.CallingApp.Nanonis.findvi("2Ch bias_AO sweeper.vi");
            app.vi = app.CallingApp.Nanonis.vilist(index).viobj;
        end

        % Close request function: CBOScanUIFigure
        function CBOScanUIFigureCloseRequest(app, event)
            app.CallingApp.OpenExperimentPanelButton.Enable = 'on';  
            app.CallingApp.InProgressLamp.Color = 'g';
            delete(app);
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.InprogressLamp.Color = 'r';
            app.vi.Run(true);
            
            while(length(dir([session,'\',basename,'*.dat'])) < app.NumberofPointsYEditField.Value)
                ax1 = app.UIAxes;
                ax2 = app.UIAxes_2;
                AppBSliveplot(app.StartVumEditField.Value, app.EndVEditField.Value, ax1, ax2, app.CallingApp.Session, app.BasenameEditField.Value);
                view(ax1, 2); view(ax2, 2);
                pause(30);  % Update every 30s
            end
            app.InprogressLamp.Color = 'k';
        end

        % Button pushed function: OpenVIButton
        function OpenVIButtonPushed(app, event)
            winopen 'C:\Users\STM\Documents\LabVIEW Data\Nanonis PI\2Ch bias_AO sweeper.vi';
        end

        % Value changed function: StartVEditField
        function StartVEditFieldValueChanged(app, event)
            value = app.StartVEditField.Value;
            app.vi.SetControlValue('Y Lower limit', value);
        end

        % Value changed function: EndVEditField
        function EndVEditFieldValueChanged(app, event)
            value = app.EndVEditField.Value;
            app.vi.SetControlValue('Y Upper limit', value);
        end

        % Value changed function: NumberofPointsYEditField
        function NumberofPointsYEditFieldValueChanged(app, event)
            value = app.NumberofPointsYEditField.Value;
            app.vi.SetControlValue('Number of Points Y', value);
        end

        % Value changed function: BasenameEditField
        function BasenameEditFieldValueChanged(app, event)

            value = app.BasenameEditField.Value;
            app.vi.SetControlValue('Save basename (empty= no change)', value);
        end

        % Value changed function: AOSignalDropDown
        function AOSignalDropDownValueChanged(app, event)
            value = app.AOSignalDropDown.Value;
            app.vi.SetControlValue('Y Signal Name (empty=no Signal)', value);
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            app.vi.Abort;
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            ax1 = app.UIAxes;
            ax2 = app.UIAxes_2;
            AppBSliveplot(app.StartVEditField.Value, app.EndVEditField.Value, ax1, ax2, app.CallingApp.Session, app.BasenameEditField.Value);
            view(ax1, 2); view(ax2, 2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CBOScanUIFigure = uifigure('Visible', 'off');
            app.CBOScanUIFigure.Name = 'CBO Scan';
            app.CBOScanUIFigure.CloseRequestFcn = createCallbackFcn(app, @CBOScanUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.CBOScanUIFigure);
            title(app.UIAxes, 'CBO Scan - DC')
            xlabel(app.UIAxes, 'Gate/V')
            ylabel(app.UIAxes, 'V_{SET}/mV')
            app.TextArea = uitextarea(app.CBOScanUIFigure);
            app.TextArea.Value = {'NOTE'; 'Edit bias spectroscopy parameters in Nanonis module'; 'Available: 2, 3, 4, 8'};
            app.UpdatePlotButton = uibutton(app.CBOScanUIFigure, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.OpenVIButton = uibutton(app.CBOScanUIFigure, 'push');
            app.OpenVIButton.ButtonPushedFcn = createCallbackFcn(app, @OpenVIButtonPushed, true);
            app.OpenVIButton.Text = 'Open VI';
            app.StartButton = uibutton(app.CBOScanUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ABORTButton = uibutton(app.CBOScanUIFigure, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Text = 'ABORT';
            app.UIAxes_2 = uiaxes(app.CBOScanUIFigure);
            title(app.UIAxes_2, 'CBO Scan - AC')
            xlabel(app.UIAxes_2, 'Gate/V')
            ylabel(app.UIAxes_2, 'V_{SET}/mV')
            app.StartVEditFieldLabel = uilabel(app.CBOScanUIFigure);
            app.StartVEditFieldLabel.Text = 'Start/V';
            app.StartVEditField = uieditfield(app.CBOScanUIFigure, 'numeric');
            app.StartVEditField.ValueChangedFcn = createCallbackFcn(app, @StartVEditFieldValueChanged, true);
            app.EndVEditFieldLabel = uilabel(app.CBOScanUIFigure);
            app.EndVEditFieldLabel.Text = 'End/V';
            app.EndVEditField = uieditfield(app.CBOScanUIFigure, 'numeric');
            app.EndVEditField.ValueChangedFcn = createCallbackFcn(app, @EndVEditFieldValueChanged, true);
            app.BasenameEditFieldLabel = uilabel(app.CBOScanUIFigure);
            app.BasenameEditFieldLabel.Text = 'Basename';
            app.BasenameEditField = uieditfield(app.CBOScanUIFigure, 'text');
            app.BasenameEditField.ValueChangedFcn = createCallbackFcn(app, @BasenameEditFieldValueChanged, true);
            app.NumberofPointsYEditFieldLabel = uilabel(app.CBOScanUIFigure);
            app.NumberofPointsYEditFieldLabel.Text = 'Number of Points Y';
            app.NumberofPointsYEditField = uieditfield(app.CBOScanUIFigure, 'numeric');
            app.NumberofPointsYEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPointsYEditFieldValueChanged, true);
            app.InprogressLampLabel = uilabel(app.CBOScanUIFigure);
            app.InprogressLampLabel.Text = 'In progress';
            app.InprogressLamp = uilamp(app.CBOScanUIFigure);
            app.AOSignalDropDownLabel = uilabel(app.CBOScanUIFigure);
            app.AOSignalDropDownLabel.Text = 'AO Signal';
            app.AOSignalDropDown = uidropdown(app.CBOScanUIFigure);
            app.AOSignalDropDown.Items = {'Output 2 (V)', 'Output 3 (V)', 'Output 4 (V)', 'Output 8 (V)'};
            app.AOSignalDropDown.ValueChangedFcn = createCallbackFcn(app, @AOSignalDropDownValueChanged, true);
            app.AOSignalDropDown.Value = 'Output 2 (V)';
            % Show the figure after all components are created
            app.CBOScanUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CBO_Scan_UI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CBOScanUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CBOScanUIFigure)
        end
    end
end