classdef Compressibility_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StartVEditFieldLabel          matlab.ui.control.Label
        StartVEditField               matlab.ui.control.NumericEditField
        StepsEditFieldLabel           matlab.ui.control.Label
        StepsEditField                matlab.ui.control.NumericEditField
        PeriodsEditFieldLabel         matlab.ui.control.Label
        PeriodsEditField              matlab.ui.control.NumericEditField
        BasenameEditFieldLabel        matlab.ui.control.Label
        BasenameEditField             matlab.ui.control.EditField
        EndVEditFieldLabel            matlab.ui.control.Label
        EndVEditField                 matlab.ui.control.NumericEditField
        StartButton                   matlab.ui.control.Button
        CompressibilityonGridButton   matlab.ui.control.Button
        CompressibilityonCloudButton  matlab.ui.control.Button
        CompressibilityonLineButton   matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        OpenVIButton                  matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        UpdateSETWorkingParametersButton  matlab.ui.control.Button
        InProgressLampLabel           matlab.ui.control.Label
        InProgressLamp                matlab.ui.control.Lamp
        RealTimePlotSwitchLabel       matlab.ui.control.Label
        RealTimePlotSwitch            matlab.ui.control.ToggleSwitch
        ABORTButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp;  % Description        
        vi; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SPMUI)
            app.CallingApp = SPMUI;
            index = app.CallingApp.Nanonis.findvi("Bias compressibility.vi");
            app.vi = app.CallingApp.Nanonis.vilist(index).viobj;
            app.UIFigure.AutoResizeChildren = 'off'; % For subplot
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.OpenExperimentPanelButton.Enable = 'on';  
            app.CallingApp.InProgressLamp.Color = 'g';
            delete(app)
            
        end

        % Button pushed function: OpenVIButton
        function OpenVIButtonPushed(app, event)
            winopen 'C:\Users\STM\Documents\LabVIEW Data\Nanonis PI\Bias compressibility.vi';
        end

        % Button pushed function: UpdateSETWorkingParametersButton
        function UpdateSETWorkingParametersButtonPushed(app, event)
            app.CallingApp.ObtainSETWorkingParametersButtonPushed(event);
        end

        % Button pushed function: CompressibilityonCloudButton
        function CompressibilityonCloudButtonPushed(app, event)
            app.InProgressLamp.Color = 'r';
            Cloud_compressibility(app.CallingApp.Session, app.BasenameEditField.Value, app.StartVEditField.Value, app.EndVEditField.Value, app.StepsEditField.Value, app.PeriodsEditField.Value);
            app.InProgressLamp.Color = 'k';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.InProgressLamp.Color = 'r';
            app.vi.SetControlValue('Start (V)', app.StartVEditField.Value);
            app.vi.SetControlValue('End (V)', app.EndVEditField.Value);
            app.vi.SetControlValue('N points', app.StepsEditField.Value);
            app.vi.SetControlValue('Average period (s)', app.PeriodsEditField.Value);
            app.vi.SetControlValue('Basename', app.BasenameEditField.Value);
            app.vi.Run(true);


            % Live plot
            value = app.RealTimePlotSwitch.Value;
            switch value
                case 'on'
                    app.CallingApp.findnewdata;
                    index = app.CallingApp.NumEditField.Value;
                    filepath = [app.CallingApp.Session, app.BasenameEditField.Value, index, '.dat'];
                    ax1 = subplot(2,3,1,'Parent', app.UIFigure);
                    ax2 = subplot(2,3,2,'Parent', app.UIFigure);
                    ax3 = subplot(2,3,3,'Parent', app.UIFigure);
                    ax4 = subplot(2,3,4,'Parent', app.UIFigure);
                    ax5 = subplot(2,3,5,'Parent', app.UIFigure);
                    ax6 = subplot(2,3,6,'Parent', app.UIFigure);
                    
                    Steps = app.vi.GetControlValue('Steps done');
                    while Steps < app.StepsEditField.Value
                        AppCompAnalysis(ax1, ax2, ax3, ax4, ax5, ax6, filepath);
                        pause(1); % update every 1 second
                    end
                    
            end
            
            app.InProgressLamp.Color = 'k';
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            app.vi.Abort;
            app.InprogressLamp.Color = 'k';
        end

        % Value changed function: RealTimePlotSwitch
        function RealTimePlotSwitchValueChanged(app, event)
            value = app.RealTimePlotSwitch.Value;
            switch value  % Toggle real time plotting
                case 'on'
                    index = app.CallingApp.Nanonis.findvi("Bias compressibility RT.vi");
                    app.vi = app.CallingApp.Nanonis.vilist(index).viobj;
                case 'off'
                    index = app.CallingApp.Nanonis.findvi("Bias compressibility.vi");
                    app.vi = app.CallingApp.Nanonis.vilist(index).viobj;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartVEditFieldLabel = uilabel(app.UIFigure);
            app.StartVEditFieldLabel.Text = 'Start/V';
            app.StartVEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsEditFieldLabel = uilabel(app.UIFigure);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodsEditFieldLabel.Text = 'Period/s';
            app.PeriodsEditField = uieditfield(app.UIFigure, 'numeric');
            app.BasenameEditFieldLabel = uilabel(app.UIFigure);
            app.BasenameEditFieldLabel.Text = 'Base name';
            app.BasenameEditField = uieditfield(app.UIFigure, 'text');
            app.EndVEditFieldLabel = uilabel(app.UIFigure);
            app.EndVEditFieldLabel.Text = 'End/V';
            app.EndVEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CompressibilityonGridButton = uibutton(app.UIFigure, 'push');
            app.CompressibilityonGridButton.Text = 'Compressibility on Grid';
            app.CompressibilityonCloudButton = uibutton(app.UIFigure, 'push');
            app.CompressibilityonCloudButton.ButtonPushedFcn = createCallbackFcn(app, @CompressibilityonCloudButtonPushed, true);
            app.CompressibilityonCloudButton.Text = 'Compressibility on Cloud';
            app.CompressibilityonLineButton = uibutton(app.UIFigure, 'push');
            app.CompressibilityonLineButton.Text = 'Compressibility on Line';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Compressibility')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.OpenVIButton = uibutton(app.UIFigure, 'push');
            app.OpenVIButton.ButtonPushedFcn = createCallbackFcn(app, @OpenVIButtonPushed, true);
            app.OpenVIButton.Text = 'Open VI';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'NOTE'; 'Grid defined in Nanonis Scan Control Module'; ''};
            app.UpdateSETWorkingParametersButton = uibutton(app.UIFigure, 'push');
            app.UpdateSETWorkingParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSETWorkingParametersButtonPushed, true);
            app.UpdateSETWorkingParametersButton.Text = 'Update SET Working Parameters';
            app.InProgressLampLabel = uilabel(app.UIFigure);
            app.InProgressLampLabel.Text = 'In Progress';
            app.InProgressLamp = uilamp(app.UIFigure);
            app.RealTimePlotSwitchLabel = uilabel(app.UIFigure);
            app.RealTimePlotSwitchLabel.Text = {'Real'; 'Time'; 'Plot'};
            app.RealTimePlotSwitch = uiswitch(app.UIFigure, 'toggle');
            app.RealTimePlotSwitch.ValueChangedFcn = createCallbackFcn(app, @RealTimePlotSwitchValueChanged, true);
            app.ABORTButton = uibutton(app.UIFigure, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Text = 'ABORT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Compressibility_UI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end