classdef Keithley2450_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Keithley2450UIUIFigure        matlab.ui.Figure
        VoltageoutputVEditFieldLabel  matlab.ui.control.Label
        VoltageoutputVEditField       matlab.ui.control.NumericEditField
        CurrentoutputAEditFieldLabel  matlab.ui.control.Label
        CurrentoutputAEditField       matlab.ui.control.NumericEditField
        RampVButton                   matlab.ui.control.Button
        ZeroButton                    matlab.ui.control.Button
        OutputLampLabel               matlab.ui.control.Label
        OutputLamp                    matlab.ui.control.Lamp
        ReadVButton                   matlab.ui.control.Button
        ReadIButton                   matlab.ui.control.Button
        RangeSelectButtonGroup        matlab.ui.container.ButtonGroup
        mVButton                      matlab.ui.control.ToggleButton
        mVButton_2                    matlab.ui.control.ToggleButton
        VButton                       matlab.ui.control.ToggleButton
        VButton_2                     matlab.ui.control.Button
        VButton_3                     matlab.ui.control.Button
        nAButton                      matlab.ui.control.Button
        nAButton_2                    matlab.ui.control.Button
        uAButton                      matlab.ui.control.Button
        uAButton_2                    matlab.ui.control.Button
        uAButton_3                    matlab.ui.control.Button
        AutoButton                    matlab.ui.control.Button
        AutoButton_2                  matlab.ui.control.Button
        CurrentlimitAEditFieldLabel   matlab.ui.control.Label
        CurrentlimitAEditField        matlab.ui.control.NumericEditField
        VoltagelimitVEditFieldLabel   matlab.ui.control.Label
        OutputOnButton                matlab.ui.control.StateButton
        Keithley2450FrontPanel        matlab.ui.container.Panel
        ConnectButton                 matlab.ui.control.Button
        VISAIDEditFieldLabel          matlab.ui.control.Label
        VISAIDEditField               matlab.ui.control.EditField
        DisconnectButton              matlab.ui.control.Button
        MessageEditFieldLabel         matlab.ui.control.Label
        MessageEditField              matlab.ui.control.EditField
        VoltagelimitVEditField        matlab.ui.control.NumericEditField
        ModeSwitch                    matlab.ui.control.Switch
        RampIButton                   matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        SetButton                     matlab.ui.control.Button
        SetButton_2                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        K2450; % Description
        ID; % VISA ID
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, id)
            app.K2450 = Keithley2450_USB(id);   % One instance of Keithley 2450 class is stored as a property
                                                % of the UI class instance
                                                % Start up by specifying
                                                % equipment ID
                                                % id is a string/char array
            app.ID = id;
            app.VISAIDEditField.Value = ['USB0::0x05E6::0x2450::',id,'::0::INSTR'];
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Find a VISA-Serial object.
            obj1 = instrfind('Type', 'visa-usb', 'RsrcName', app.VISAIDEditField.Value, 'Tag', '');
       
            % Create the VISA-Serial object if it does not exist
            % otherwise use the object that was found.
            if isempty(obj1)
                obj1 = visa('NI', 'ASRL4::INSTR');
            else
            fclose(obj1);
            obj1 = obj1(1);
            app.K2450.visaobj = obj1;
            end

            % Connect to instrument object, obj1.
            fopen(obj1);
            % Define terminator
            app.K2450.visaobj.EOSCharCode = 'CR';
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            fclose(app.K2450.visaobj);
            delete(app.K2450.visaobj);
            app.K2450.visaobj = [];
            app.K2450 = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Keithley2450UIUIFigure = uifigure('Visible', 'off');
            app.Keithley2450UIUIFigure.Name = 'Keithley2450 UI';
            app.VoltageoutputVEditFieldLabel = uilabel(app.Keithley2450UIUIFigure);
            app.VoltageoutputVEditFieldLabel.Text = 'Voltage output/V';
            app.VoltageoutputVEditField = uieditfield(app.Keithley2450UIUIFigure, 'numeric');
            app.CurrentoutputAEditFieldLabel = uilabel(app.Keithley2450UIUIFigure);
            app.CurrentoutputAEditFieldLabel.Text = 'Current output/A';
            app.CurrentoutputAEditField = uieditfield(app.Keithley2450UIUIFigure, 'numeric');
            app.RampVButton = uibutton(app.Keithley2450UIUIFigure, 'push');
            app.RampVButton.Text = 'Ramp V';
            app.ZeroButton = uibutton(app.Keithley2450UIUIFigure, 'push');
            app.ZeroButton.Text = 'Zero';
            app.RampIButton = uibutton(app.Keithley2450UIUIFigure, 'push');
            app.RampIButton.Text = 'Ramp I';
            app.OutputLampLabel = uilabel(app.Keithley2450UIUIFigure);
            app.OutputLampLabel.Text = 'Output';
            app.OutputLamp = uilamp(app.Keithley2450UIUIFigure);
            app.ReadVButton = uibutton(app.Keithley2450UIUIFigure, 'push');
            app.ReadVButton.Text = 'Read V';
            app.ReadIButton = uibutton(app.Keithley2450UIUIFigure, 'push');
            app.ReadIButton.Text = 'Read I ';
            app.ModeSwitch = uiswitch(app.Keithley2450UIUIFigure, 'slider');
            app.ModeSwitch.Items = {'Source V', 'Source I'};
            app.ModeSwitch.Value = 'Source V';
            app.RangeSelectButtonGroup = uibuttongroup(app.Keithley2450UIUIFigure);
            app.RangeSelectButtonGroup.Title = 'Range Select';
            app.mVButton = uitogglebutton(app.RangeSelectButtonGroup);
            app.mVButton.Text = '20 mV';
            app.mVButton.Value = true;
            app.mVButton_2 = uitogglebutton(app.RangeSelectButtonGroup);
            app.mVButton_2.Text = '200 mV';
            app.VButton = uitogglebutton(app.RangeSelectButtonGroup);
            app.VButton.Text = '2 V';
            app.VButton_2 = uibutton(app.RangeSelectButtonGroup, 'push');
            app.VButton_2.Text = '20 V';
            app.VButton_3 = uibutton(app.RangeSelectButtonGroup, 'push');
            app.VButton_3.Text = '200 V';
            app.nAButton = uibutton(app.RangeSelectButtonGroup, 'push');
            app.nAButton.Text = '10 nA';
            app.nAButton_2 = uibutton(app.RangeSelectButtonGroup, 'push');
            app.nAButton_2.Text = '100 nA';
            app.uAButton = uibutton(app.RangeSelectButtonGroup, 'push');
            app.uAButton.Text = '1 uA';
            app.uAButton_2 = uibutton(app.RangeSelectButtonGroup, 'push');
            app.uAButton_2.Text = '10 uA';
            app.uAButton_3 = uibutton(app.RangeSelectButtonGroup, 'push');
            app.uAButton_3.Text = '100 uA';
            app.AutoButton = uibutton(app.RangeSelectButtonGroup, 'push');
            app.AutoButton.Text = 'Auto';
            app.AutoButton_2 = uibutton(app.RangeSelectButtonGroup, 'push');
            app.AutoButton_2.Text = 'Auto';
            app.CurrentlimitAEditFieldLabel = uilabel(app.Keithley2450UIUIFigure);
            app.CurrentlimitAEditFieldLabel.Text = 'Current limit/A';
            app.CurrentlimitAEditField = uieditfield(app.Keithley2450UIUIFigure, 'numeric');
            app.VoltagelimitVEditFieldLabel = uilabel(app.Keithley2450UIUIFigure);
            app.VoltagelimitVEditFieldLabel.Text = 'Voltage limit/V';
            app.VoltagelimitVEditField = uieditfield(app.Keithley2450UIUIFigure, 'numeric');
            app.OutputOnButton = uibutton(app.Keithley2450UIUIFigure, 'state');
            app.OutputOnButton.Text = 'Output On';
            app.Keithley2450FrontPanel = uipanel(app.Keithley2450UIUIFigure);
            app.Keithley2450FrontPanel.Title = 'Keithley 2450 Front Panel';
            app.VISAIDEditFieldLabel = uilabel(app.Keithley2450FrontPanel);
            app.VISAIDEditFieldLabel.Text = 'VISA ID';
            app.VISAIDEditField = uieditfield(app.Keithley2450FrontPanel, 'text');
            app.VISAIDEditField.Value = 'USB0::0x05E6::0x2450::04433807::0::INSTR';
            app.ConnectButton = uibutton(app.Keithley2450FrontPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.Keithley2450FrontPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.MessageEditFieldLabel = uilabel(app.Keithley2450FrontPanel);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.Keithley2450FrontPanel, 'text');
            app.RefreshButton = uibutton(app.Keithley2450FrontPanel, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.SetButton = uibutton(app.Keithley2450UIUIFigure, 'push');
            app.SetButton.Text = 'Set';
            app.SetButton_2 = uibutton(app.Keithley2450UIUIFigure, 'push');
            app.SetButton_2.Text = 'Set';
            % Show the figure after all components are created
            app.Keithley2450UIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Keithley2450_UI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Keithley2450UIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Keithley2450UIUIFigure)
        end
    end
end