classdef SPMUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        sSETControl                    matlab.ui.Figure
        ConsoleMenu                    matlab.ui.container.Menu
        NanonisHardwareCommunicationControlPanel  matlab.ui.container.Panel
        GetsessionButton               matlab.ui.control.Button
        PathTextAreaLabel              matlab.ui.control.Label
        PathTextArea                   matlab.ui.control.TextArea
        LoadFunctionsButton            matlab.ui.control.Button
        VIListListBox                  matlab.ui.control.ListBox
        OpenButton                     matlab.ui.control.Button
        OpenVIFileButton               matlab.ui.control.Button
        InstrumentsListBoxLabel        matlab.ui.control.Label
        InstrumentsListBox             matlab.ui.control.ListBox
        ConnectButton                  matlab.ui.control.Button
        QueryEditFieldLabel            matlab.ui.control.Label
        QueryEditField                 matlab.ui.control.EditField
        QueryButton                    matlab.ui.control.Button
        WriteEditFieldLabel            matlab.ui.control.Label
        WriteEditField                 matlab.ui.control.EditField
        SystemProcessAutomationPanel   matlab.ui.container.Panel
        AutorecondensationButton       matlab.ui.control.Button
        StatusLampLabel                matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        DataAnalysisPanel              matlab.ui.container.Panel
        BasenameEditFieldLabel         matlab.ui.control.Label
        Basename                       matlab.ui.control.EditField
        NumEditFieldLabel              matlab.ui.control.Label
        NumEditField                   matlab.ui.control.EditField
        LowerEditFieldLabel            matlab.ui.control.Label
        LowerEditField                 matlab.ui.control.NumericEditField
        UpperEditFieldLabel            matlab.ui.control.Label
        UpperEditField                 matlab.ui.control.NumericEditField
        AnalysisFunctionsButtonGroup   matlab.ui.container.ButtonGroup
        CBOSinefitButton               matlab.ui.control.Button
        ZAnalysisButton                matlab.ui.control.Button
        CBOMapButton                   matlab.ui.control.Button
        CBOvsZButton                   matlab.ui.control.Button
        ImportBStoWSButton             matlab.ui.control.Button
        ImportScanButton               matlab.ui.control.Button
        ImportComptoWSButton           matlab.ui.control.Button
        CompAnalysisButton             matlab.ui.control.Button
        FindBestFitButton              matlab.ui.control.Button
        PeriodEditFieldLabel           matlab.ui.control.Label
        PeriodEditField                matlab.ui.control.NumericEditField
        NewButton                      matlab.ui.control.Button
        PathButton                     matlab.ui.control.Button
        MagnetControlPanel             matlab.ui.container.Panel
        TemperatureControlPanel        matlab.ui.container.Panel
        SetpointKEditFieldLabel        matlab.ui.control.Label
        SetpointKEditField             matlab.ui.control.NumericEditField
        RangeDropDownLabel             matlab.ui.control.Label
        RangeDropDown                  matlab.ui.control.DropDown
        SendButton                     matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        TempKEditFieldLabel            matlab.ui.control.Label
        TempKEditField                 matlab.ui.control.NumericEditField
        ChannelDropDownLabel           matlab.ui.control.Label
        ChannelDropDown                matlab.ui.control.DropDown
        ReadButton                     matlab.ui.control.Button
        ExperimentsPanel               matlab.ui.container.Panel
        ExperimentDropDownLabel        matlab.ui.control.Label
        ExperimentDropDown             matlab.ui.control.DropDown
        OpenExperimentPanelButton      matlab.ui.control.Button
        InProgressLampLabel            matlab.ui.control.Label
        InProgressLamp                 matlab.ui.control.Lamp
        ScannerControlPanel            matlab.ui.container.Panel
        SafeButton                     matlab.ui.control.Button
        ZumEditFieldLabel              matlab.ui.control.Label
        ZumEditField                   matlab.ui.control.NumericEditField
        GoButton                       matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        SETConfigurationPanel          matlab.ui.container.Panel
        SetpointAEditFieldLabel        matlab.ui.control.Label
        SetpointAEditField             matlab.ui.control.NumericEditField
        PeriodVEditFieldLabel          matlab.ui.control.Label
        PeriodVEditField               matlab.ui.control.NumericEditField
        AmplitudeAEditFieldLabel       matlab.ui.control.Label
        AmplitudeAEditField            matlab.ui.control.NumericEditField
        V2dMaxVEditFieldLabel          matlab.ui.control.Label
        V2dMaxVEditField               matlab.ui.control.NumericEditField
        V2dMinVEditFieldLabel          matlab.ui.control.Label
        V2dMinVEditField               matlab.ui.control.NumericEditField
        GainEditFieldLabel             matlab.ui.control.Label
        GainEditField                  matlab.ui.control.NumericEditField
        CheckSETWorkingParametersButton  matlab.ui.control.Button
        WriteSETGlobalButton           matlab.ui.control.Button
        FetchButton                    matlab.ui.control.Button
        SampleConfigurationPanel       matlab.ui.container.Panel
        VbgLimitVEditFieldLabel        matlab.ui.control.Label
        VbgLimitVEditField             matlab.ui.control.NumericEditField
        MeasurementConfigurationPanel  matlab.ui.container.Panel
        V_SETLabel                     matlab.ui.control.Label
        V_SETEditField                 matlab.ui.control.EditField
        V_2DLabel                      matlab.ui.control.Label
        V_2DEditField                  matlab.ui.control.EditField
        GateEditFieldLabel             matlab.ui.control.Label
        GateEditField                  matlab.ui.control.EditField
        I_BGEditFieldLabel             matlab.ui.control.Label
        I_BGEditField                  matlab.ui.control.EditField
        I_2DEditFieldLabel             matlab.ui.control.Label
        I_2DEditField                  matlab.ui.control.EditField
        OpenRackButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        Nanonis; % Nanonis class object
        DialogApp; % Description
        Session; % Description
        Lakeshore;
    end
    
    methods (Access = public)

        function findnewdata(app)
        % Used in data analysis panel
        % Find the newest datafile with the specified basename
            if strcmp(string(app.NumEditField.Value), "")==1    % If no index specified, fit the newest datafile
               % with specified basename
               list = dir([char(app.PathTextArea.Value),'\',char(app.Basename.Value),'*.dat']);
               index = sscanf(list(end).name, [app.Basename.Value, '%d', '.dat']);
               if index < 1
               error('No datafile with basename found');
               else if index < 10
               app.NumEditField.Value = strcat("0000", num2str(index));
               else if 10 <= index <100
               app.NumEditField.Value = strcat("000", num2str(index));
               else if 100 <= index < 999
               app.NumEditField.Value = strcat("00", num2str(index));
               end
               end
               end
               end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Nanonis = Nanonis();        % Create a Nanonis class object as an app property
            app.PathTextArea.Value = app.Nanonis.Get_session;    % Get current session folder when booting

            vis = app.Nanonis.Load_vi;
            app.VIListListBox.Items = extractfield(vis,'viname');
            app.VIListListBox.ItemsData = vis;
            app.Lakeshore = Lakeshore350();
        end

        % Button pushed function: GetsessionButton
        function GetsessionButtonPushed(app, event)
            path = app.Nanonis.Get_session;
            app.PathTextArea.Value = path;
            app.Session = path;
        end

        % Button pushed function: AutorecondensationButton
        function AutorecondensationButtonPushed(app, event)
            app.StatusLamp = 1;
            Auto_recondensation;
            app.StatusLamp = 0;
        end

        % Button pushed function: SafeButton
        function SafeButtonPushed(app, event)
            index = app.Nanonis.findvi("Back to safe.vi");
            vi = app.Nanonis.vilist(index).viobj;
            vi.Run(true);
        end

        % Button pushed function: LoadFunctionsButton
        function LoadFunctionsButtonPushed(app, event)
            vis = app.Nanonis.Load_vi_static;
            app.VIListListBox.Items = extractfield(vis,'viname');
            app.VIListListBox.ItemsData = vis;
        end

        % Button pushed function: ImportBStoWSButton
        function ImportBStoWSButtonPushed(app, event)

            path = strcat(string(app.PathTextArea.Value), '\', string(app.Basename.Value), string(app.NumEditField.Value), '.dat');
            [Bias, Current, Input_2] = importbiasspectr(path);
            assignin('base', "Bias", Bias);
            assignin('base', "Current", Current);
            assignin('base', "Input_2", Input_2);
        end

        % Button pushed function: CBOvsZButton
        function CBOvsZButtonPushed(app, event)
            BSliveplot(char(app.PathTextArea.Value), char(app.Basename.Value));
        end

        % Button pushed function: CBOMapButton
        function CBOMapButtonPushed(app, event)
            BSliveplot(char(app.PathTextArea.Value), char(app.Basename.Value));
        end

        % Button pushed function: CBOSinefitButton
        function CBOSinefitButtonPushed(app, event)
%             app.findnewdata;
            path = strcat(string(app.PathTextArea.Value), '\', string(app.Basename.Value), string(app.NumEditField.Value), '.dat');
            [x,y]=importbiasspectr(path);
            if app.PeriodEditField.Value == 0
                s = sinefit(x(app.LowerEditField.Value:app.UpperEditField.Value),y(app.LowerEditField.Value:app.UpperEditField.Value));
            else
                s = sinefit(x(app.LowerEditField.Value:app.UpperEditField.Value),y(app.LowerEditField.Value:app.UpperEditField.Value),app.PeriodEditField.Value);
            end
            app.PeriodEditField.Value = s(2);
        end

        % Button pushed function: OpenExperimentPanelButton
        function OpenExperimentPanelButtonPushed(app, event)
            % Callback that opens experiment panels
            % Pass SPMUI as argument to the dialog app to allow access to
            % public properties in SPMUI main app
            app.InProgressLamp.Color = 'y';
            value = app.ExperimentDropDown.Value;
            switch value
                case 'Z CBO Spectroscopy'
                    app.OpenExperimentPanelButton.Enable = 'off';
                    app.DialogApp = Z_CBO_Spectroscopy_UI(app);
                case 'CBO Scan'
                    app.OpenExperimentPanelButton.Enable = 'off';
                    app.DialogApp = CBO_Scan_UI(app);
                case 'Coarse Approach'
                case 'Fine Approach'
                    
                case 'Compressibility'
                    app.OpenExperimentPanelButton.Enable = 'off';
                    app.DialogApp = Compressibility_UI(app);
            end
                
        end

        % Button pushed function: OpenVIFileButton
        function OpenVIFileButtonPushed(app, event)
            winopen(['C:\Users\STM\Documents\LabVIEW Data\Nanonis PI\', app.VIListListBox.Value.viname]);
            % Open LV VI file front pane
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            index = app.Nanonis.findvi("Z ramp.vi");
            vi = app.Nanonis.vilist(index).viobj;
            vi.SetControlValue('Target', app.ZumEditField.Value*1E-6);
            vi.Run(true);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            index = app.Nanonis.findvi("Z ramp.vi");
            vi = app.Nanonis.vilist(index).viobj;
            vi.SetControlValue('Target', app.ZumEditField.Value*1E-6);
            vi.Abort;
        end

        % Button pushed function: CompAnalysisButton
        function CompAnalysisButtonPushed(app, event)
            app.findnewdata;
            path = strcat(string(app.PathTextArea.Value), '\', string(app.Basename.Value), string(app.NumEditField.Value), '.dat');
            compress_analysis(path);
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
        list = dir([char(app.PathTextArea.Value),'\',char(app.Basename.Value),'*.dat']);
        index = sscanf(list(end).name, [app.Basename.Value, '%d', '.dat']);
               if index < 1
               error('No datafile with basename found');
               else if index < 10
               app.NumEditField.Value = strcat("0000", num2str(index));
               else if 10 <= index && index < 100
               app.NumEditField.Value = strcat("000", num2str(index));
               else if 100 <= index&& index < 999
               app.NumEditField.Value = strcat("00", num2str(index));
               end
               end
               end
               end
        end

        % Button pushed function: ImportComptoWSButton
        function ImportComptoWSButtonPushed(app, event)
            path = strcat(string(app.PathTextArea.Value), '\', string(app.Basename.Value), string(app.NumEditField.Value), '.dat');
            [CurrentA, BiasV, Output3V, Input2V, Input3V, LIDemod1XA, LIDemod1YA, VbgV] = importcompressibility(path);
            assignin('base', "CurrentA", CurrentA);
            assignin('base', "BiasV", BiasV);
            assignin('base', 'Output3V', Output3V);
            assignin('base', "Input2V", Input2V);
            assignin('base', "Input3V", Input3V);
            assignin('base', "LIDemod1XA", LIDemod1XA);
            assignin('base', 'LIDemod1YA', LIDemod1YA);
            assignin('base', "VbgV", VbgV);
        end

        % Button pushed function: FindBestFitButton
        function FindBestFitButtonPushed(app, event)
            app.findnewdata;
            path = strcat(string(app.PathTextArea.Value), '\', string(app.Basename.Value), string(app.NumEditField.Value), '.dat');
            [x,y]=importbiasspectr(path);
            [s(1), s(2)] = findbestfit_np(x,y,app.UpperEditField.Value - app.LowerEditField.Value);
            app.PeriodEditField.Value = s(2);
        end

        % Button pushed function: PathButton
        function PathButtonPushed(app, event)
            path = strcat(string(app.PathTextArea.Value), '\', string(app.Basename.Value), string(app.NumEditField.Value), '.dat');
            clipboard('copy', path);
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.Lakeshore.Setpt_set(app.SetpointKEditField.Value);
            app.Lakeshore.Sorb_set(app.RangeDropDown.Value);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            [range, setpt]  = app.Lakeshore.Refresh;
            app.SetpointKEditField.Value = str2double(setpt);
            app.RangeDropDown.Value = string(range(1));
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)

            switch app.ChannelDropDown.Value
                case 'Sorb'
                    t = app.Lakeshore.Read_temp('a');
                case '1K Pot'
                    t = app.Lakeshore.Read_temp('b');
                case 'He3'
                    t = app.Lakeshore.Read_temp('c');
                case 'STM'
                    t = app.Lakeshore.Read_temp('d');
            end
            app.TempKEditField.Value = str2double(t);
        end

        % Button pushed function: CheckSETWorkingParametersButton
        function CheckSETWorkingParametersButtonPushed(app, event)
            % Update 'SET Global.vi' through findbestfit
            update_set_para(app.Session, app.V_2DEditField.Value);
            index = app.Nanonis.findvi('SET global.vi');
            vi = app.Nanonis.vilist(index).viobj;
            % Fetch parameters from 'SET Global.vi'
            app.SetpointAEditField.Value = vi.GetControlValue('Setpt');
            app.PeriodVEditField.Value = vi.GetControlValue('Period');
            app.AmplitudeAEditField.Value = vi.GetControlValue('Amplitude');
            app.V2dMaxVEditField.Value = vi.GetControlValue('V2dmax');
            app.V2dMinVEditField.Value = vi.GetControlValue('V2dmin');
        end

        % Button pushed function: WriteSETGlobalButton
        function WriteSETGlobalButtonPushed(app, event)
            index = app.Nanonis.findvi('SET global.vi');
            vi = app.Nanonis.vilist(index).viobj;
            % Update 'SET Global.vi' from app
            vi.SetControlValue('Setpt', app.SetpointAEditField.Value);
            vi.SetControlValue('Period', app.PeriodVEditField.Value);
            vi.SetControlValue('Amplitude', app.AmplitudeAEditField.Value);
            vi.SetControlValue('V2dmax', app.V2dMaxVEditField.Value);
            vi.SetControlValue('V2dmin', app.V2dMinVEditField.Value);
        end

        % Button pushed function: ZAnalysisButton
        function ZAnalysisButtonPushed(app, event)
            Z_analysis(app.PathTextArea.Value, app.Basename.Value, 0, 4.25E-6, app.UpperEditField.Value - app.LowerEditField.Value);
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
            index = app.Nanonis.findvi('SET global.vi');
            vi = app.Nanonis.vilist(index).viobj;
            % Read SET parameters from 'SET Global.vi'
            app.SetpointAEditField.Value = vi.GetControlValue('Setpt');
            app.PeriodVEditField.Value = vi.GetControlValue('Period');
            app.AmplitudeAEditField.Value = vi.GetControlValue('Amplitude');
            app.V2dMaxVEditField.Value = vi.GetControlValue('V2dmax');
            app.V2dMinVEditField.Value = vi.GetControlValue('V2dmin');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.sSETControl = uifigure('Visible', 'off');
            app.sSETControl.Name = 'sSET Control';
            app.ConsoleMenu = uimenu(app.sSETControl);
            app.ConsoleMenu.Text = 'Console';
            app.NanonisHardwareCommunicationControlPanel = uipanel(app.sSETControl);
            app.NanonisHardwareCommunicationControlPanel.Title = 'Nanonis & Hardware Communication/Control';
            app.GetsessionButton = uibutton(app.NanonisHardwareCommunicationControlPanel, 'push');
            app.GetsessionButton.ButtonPushedFcn = createCallbackFcn(app, @GetsessionButtonPushed, true);
            app.GetsessionButton.Text = 'Get session';
            app.PathTextAreaLabel = uilabel(app.NanonisHardwareCommunicationControlPanel);
            app.PathTextAreaLabel.Text = 'Path';
            app.PathTextArea = uitextarea(app.NanonisHardwareCommunicationControlPanel);
            app.LoadFunctionsButton = uibutton(app.NanonisHardwareCommunicationControlPanel, 'push');
            app.LoadFunctionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFunctionsButtonPushed, true);
            app.LoadFunctionsButton.Text = 'Load Functions';
            app.VIListListBox = uilistbox(app.NanonisHardwareCommunicationControlPanel);
            app.VIListListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 10', 'Item 11', 'Item 12', 'Item 13', 'Item 16', 'Item 17', 'Item 18', 'Item 14', 'Item 19', 'Item 20', 'Item 15', 'Item 21', 'Item 24', 'Item 25', 'Item 22', 'Item 23', 'Item 26', 'Item 28', 'Item 31', 'Item 29', 'Item 30', 'Item 27', 'Item 9'};
            app.VIListListBox.Multiselect = 'on';
            app.VIListListBox.Value = {'Item 1'};
            app.OpenButton = uibutton(app.NanonisHardwareCommunicationControlPanel, 'push');
            app.OpenButton.Text = 'Open';
            app.OpenVIFileButton = uibutton(app.NanonisHardwareCommunicationControlPanel, 'push');
            app.OpenVIFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenVIFileButtonPushed, true);
            app.OpenVIFileButton.Text = 'Open VI File';
            app.InstrumentsListBoxLabel = uilabel(app.NanonisHardwareCommunicationControlPanel);
            app.InstrumentsListBoxLabel.Text = 'Instruments';
            app.InstrumentsListBox = uilistbox(app.NanonisHardwareCommunicationControlPanel);
            app.InstrumentsListBox.Items = {'Lakeshore', 'Magnet', 'SR 830 #1', 'SR 830 #2'};
            app.InstrumentsListBox.Value = 'Lakeshore';
            app.ConnectButton = uibutton(app.NanonisHardwareCommunicationControlPanel, 'push');
            app.ConnectButton.Text = 'Connect';
            app.QueryEditFieldLabel = uilabel(app.NanonisHardwareCommunicationControlPanel);
            app.QueryEditFieldLabel.Text = 'Query';
            app.QueryEditField = uieditfield(app.NanonisHardwareCommunicationControlPanel, 'text');
            app.QueryButton = uibutton(app.NanonisHardwareCommunicationControlPanel, 'push');
            app.QueryButton.Text = 'Query';
            app.WriteEditFieldLabel = uilabel(app.NanonisHardwareCommunicationControlPanel);
            app.WriteEditFieldLabel.Text = 'Write';
            app.WriteEditField = uieditfield(app.NanonisHardwareCommunicationControlPanel, 'text');
            app.SystemProcessAutomationPanel = uipanel(app.sSETControl);
            app.SystemProcessAutomationPanel.Title = 'System Process Automation';
            app.AutorecondensationButton = uibutton(app.SystemProcessAutomationPanel, 'push');
            app.AutorecondensationButton.ButtonPushedFcn = createCallbackFcn(app, @AutorecondensationButtonPushed, true);
            app.AutorecondensationButton.Text = 'Auto recondensation';
            app.StatusLampLabel = uilabel(app.SystemProcessAutomationPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.SystemProcessAutomationPanel);
            app.DataAnalysisPanel = uipanel(app.sSETControl);
            app.DataAnalysisPanel.Title = 'Data Analysis';
            app.BasenameEditFieldLabel = uilabel(app.DataAnalysisPanel);
            app.BasenameEditFieldLabel.Text = 'Base name';
            app.Basename = uieditfield(app.DataAnalysisPanel, 'text');
            app.Basename.Value = 'Scan 0926';
            app.NumEditFieldLabel = uilabel(app.DataAnalysisPanel);
            app.NumEditFieldLabel.Text = 'Num';
            app.NumEditField = uieditfield(app.DataAnalysisPanel, 'text');
            app.NumEditField.Value = '00001';
            app.LowerEditFieldLabel = uilabel(app.DataAnalysisPanel);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.DataAnalysisPanel, 'numeric');
            app.LowerEditField.Value = 200;
            app.UpperEditFieldLabel = uilabel(app.DataAnalysisPanel);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.DataAnalysisPanel, 'numeric');
            app.UpperEditField.Value = 450;
            app.AnalysisFunctionsButtonGroup = uibuttongroup(app.DataAnalysisPanel);
            app.AnalysisFunctionsButtonGroup.Title = 'Analysis Functions';
            app.CBOSinefitButton = uibutton(app.AnalysisFunctionsButtonGroup, 'push');
            app.CBOSinefitButton.ButtonPushedFcn = createCallbackFcn(app, @CBOSinefitButtonPushed, true);
            app.CBOSinefitButton.Text = 'CBO Sinefit';
            app.ZAnalysisButton = uibutton(app.AnalysisFunctionsButtonGroup, 'push');
            app.ZAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ZAnalysisButtonPushed, true);
            app.ZAnalysisButton.Text = 'Z Analysis';
            app.CBOMapButton = uibutton(app.AnalysisFunctionsButtonGroup, 'push');
            app.CBOMapButton.ButtonPushedFcn = createCallbackFcn(app, @CBOMapButtonPushed, true);
            app.CBOMapButton.Text = 'CBO Map';
            app.CBOvsZButton = uibutton(app.AnalysisFunctionsButtonGroup, 'push');
            app.CBOvsZButton.ButtonPushedFcn = createCallbackFcn(app, @CBOvsZButtonPushed, true);
            app.CBOvsZButton.Text = 'CBO vs Z';
            app.ImportBStoWSButton = uibutton(app.AnalysisFunctionsButtonGroup, 'push');
            app.ImportBStoWSButton.ButtonPushedFcn = createCallbackFcn(app, @ImportBStoWSButtonPushed, true);
            app.ImportBStoWSButton.Text = 'Import BS to WS';
            app.ImportScanButton = uibutton(app.AnalysisFunctionsButtonGroup, 'push');
            app.ImportScanButton.Text = 'Import Scan';
            app.ImportComptoWSButton = uibutton(app.AnalysisFunctionsButtonGroup, 'push');
            app.ImportComptoWSButton.ButtonPushedFcn = createCallbackFcn(app, @ImportComptoWSButtonPushed, true);
            app.ImportComptoWSButton.Text = 'Import Comp to WS';
            app.CompAnalysisButton = uibutton(app.AnalysisFunctionsButtonGroup, 'push');
            app.CompAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @CompAnalysisButtonPushed, true);
            app.CompAnalysisButton.Text = 'Comp Analysis';
            app.FindBestFitButton = uibutton(app.AnalysisFunctionsButtonGroup, 'push');
            app.FindBestFitButton.ButtonPushedFcn = createCallbackFcn(app, @FindBestFitButtonPushed, true);
            app.FindBestFitButton.Text = 'FindBestFit';
            app.PeriodEditFieldLabel = uilabel(app.DataAnalysisPanel);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.DataAnalysisPanel, 'numeric');
            app.PeriodEditField.Value = 0.29;
            app.NewButton = uibutton(app.DataAnalysisPanel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.PathButton = uibutton(app.DataAnalysisPanel, 'push');
            app.PathButton.ButtonPushedFcn = createCallbackFcn(app, @PathButtonPushed, true);
            app.PathButton.Text = 'Path';
            app.MagnetControlPanel = uipanel(app.sSETControl);
            app.MagnetControlPanel.Title = 'Magnet Control';
            app.TemperatureControlPanel = uipanel(app.sSETControl);
            app.TemperatureControlPanel.Title = 'Temperature Control';
            app.SetpointKEditFieldLabel = uilabel(app.TemperatureControlPanel);
            app.SetpointKEditFieldLabel.Text = 'Setpoint/K';
            app.SetpointKEditField = uieditfield(app.TemperatureControlPanel, 'numeric');
            app.RangeDropDownLabel = uilabel(app.TemperatureControlPanel);
            app.RangeDropDownLabel.Text = 'Range';
            app.RangeDropDown = uidropdown(app.TemperatureControlPanel);
            app.RangeDropDown.Items = {'0', '1', '2', '3', '4', '5'};
            app.RangeDropDown.Value = '0';
            app.SendButton = uibutton(app.TemperatureControlPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.RefreshButton = uibutton(app.TemperatureControlPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.TempKEditFieldLabel = uilabel(app.TemperatureControlPanel);
            app.TempKEditFieldLabel.Text = 'Temp/K';
            app.TempKEditField = uieditfield(app.TemperatureControlPanel, 'numeric');
            app.ChannelDropDownLabel = uilabel(app.TemperatureControlPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.TemperatureControlPanel);
            app.ChannelDropDown.Items = {'Sorb', '1K Pot', 'He3', 'STM'};
            app.ChannelDropDown.Value = 'Sorb';
            app.ReadButton = uibutton(app.TemperatureControlPanel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.ExperimentsPanel = uipanel(app.sSETControl);
            app.ExperimentsPanel.Title = 'Experiments';
            app.ExperimentDropDownLabel = uilabel(app.ExperimentsPanel);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.ExperimentsPanel);
            app.ExperimentDropDown.Items = {'CBO Scan', 'Z CBO Spectroscopy', 'Coarse Approach', 'Fine Approach', 'Compressibility'};
            app.ExperimentDropDown.Value = 'Compressibility';
            app.OpenExperimentPanelButton = uibutton(app.ExperimentsPanel, 'push');
            app.OpenExperimentPanelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExperimentPanelButtonPushed, true);
            app.OpenExperimentPanelButton.Text = 'Open Experiment Panel';
            app.InProgressLampLabel = uilabel(app.ExperimentsPanel);
            app.InProgressLampLabel.Text = 'In Progress';
            app.InProgressLamp = uilamp(app.ExperimentsPanel);
            app.ScannerControlPanel = uipanel(app.sSETControl);
            app.ScannerControlPanel.Title = 'Scanner Control';
            app.SafeButton = uibutton(app.ScannerControlPanel, 'push');
            app.SafeButton.ButtonPushedFcn = createCallbackFcn(app, @SafeButtonPushed, true);
            app.SafeButton.Text = 'Safe';
            app.ZumEditFieldLabel = uilabel(app.ScannerControlPanel);
            app.ZumEditFieldLabel.Text = 'Z/um';
            app.ZumEditField = uieditfield(app.ScannerControlPanel, 'numeric');
            app.GoButton = uibutton(app.ScannerControlPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.StopButton = uibutton(app.ScannerControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SETConfigurationPanel = uipanel(app.sSETControl);
            app.SETConfigurationPanel.Title = 'SET Configuration';
            app.SetpointAEditFieldLabel = uilabel(app.SETConfigurationPanel);
            app.SetpointAEditFieldLabel.Text = 'Setpoint/A';
            app.SetpointAEditField = uieditfield(app.SETConfigurationPanel, 'numeric');
            app.PeriodVEditFieldLabel = uilabel(app.SETConfigurationPanel);
            app.PeriodVEditFieldLabel.Text = 'Period/V';
            app.PeriodVEditField = uieditfield(app.SETConfigurationPanel, 'numeric');
            app.AmplitudeAEditFieldLabel = uilabel(app.SETConfigurationPanel);
            app.AmplitudeAEditFieldLabel.Text = 'Amplitude/A';
            app.AmplitudeAEditField = uieditfield(app.SETConfigurationPanel, 'numeric');
            app.V2dMaxVEditFieldLabel = uilabel(app.SETConfigurationPanel);
            app.V2dMaxVEditFieldLabel.Text = 'V2d Max/V';
            app.V2dMaxVEditField = uieditfield(app.SETConfigurationPanel, 'numeric');
            app.V2dMinVEditFieldLabel = uilabel(app.SETConfigurationPanel);
            app.V2dMinVEditFieldLabel.Text = 'V2d Min/V';
            app.V2dMinVEditField = uieditfield(app.SETConfigurationPanel, 'numeric');
            app.GainEditFieldLabel = uilabel(app.SETConfigurationPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.SETConfigurationPanel, 'numeric');
            app.GainEditField.Value = 1000000000;
            app.CheckSETWorkingParametersButton = uibutton(app.SETConfigurationPanel, 'push');
            app.CheckSETWorkingParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CheckSETWorkingParametersButtonPushed, true);
            app.CheckSETWorkingParametersButton.Text = 'Check SET Working Parameters';
            app.WriteSETGlobalButton = uibutton(app.SETConfigurationPanel, 'push');
            app.WriteSETGlobalButton.ButtonPushedFcn = createCallbackFcn(app, @WriteSETGlobalButtonPushed, true);
            app.WriteSETGlobalButton.Text = 'Write ''SET Global''';
            app.FetchButton = uibutton(app.SETConfigurationPanel, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.SampleConfigurationPanel = uipanel(app.sSETControl);
            app.SampleConfigurationPanel.Title = 'Sample Configuration';
            app.VbgLimitVEditFieldLabel = uilabel(app.SampleConfigurationPanel);
            app.VbgLimitVEditFieldLabel.Text = 'Vbg Limit/V';
            app.VbgLimitVEditField = uieditfield(app.SampleConfigurationPanel, 'numeric');
            app.MeasurementConfigurationPanel = uipanel(app.sSETControl);
            app.MeasurementConfigurationPanel.Title = 'Measurement Configuration';
            app.V_SETLabel = uilabel(app.MeasurementConfigurationPanel);
            app.V_SETLabel.Text = 'V_{SET}';
            app.V_SETEditField = uieditfield(app.MeasurementConfigurationPanel, 'text');
            app.V_SETEditField.Value = 'Output 2 (V)';
            app.V_2DLabel = uilabel(app.MeasurementConfigurationPanel);
            app.V_2DLabel.Text = 'V_{2D}';
            app.V_2DEditField = uieditfield(app.MeasurementConfigurationPanel, 'text');
            app.V_2DEditField.Value = 'Output 3 (V)';
            app.GateEditFieldLabel = uilabel(app.MeasurementConfigurationPanel);
            app.GateEditFieldLabel.Text = 'Gate';
            app.GateEditField = uieditfield(app.MeasurementConfigurationPanel, 'text');
            app.GateEditField.Value = 'Bias (V)';
            app.I_BGEditFieldLabel = uilabel(app.MeasurementConfigurationPanel);
            app.I_BGEditFieldLabel.Text = 'I_{BG}';
            app.I_BGEditField = uieditfield(app.MeasurementConfigurationPanel, 'text');
            app.I_BGEditField.Value = 'Input 2 (V)';
            app.I_2DEditFieldLabel = uilabel(app.MeasurementConfigurationPanel);
            app.I_2DEditFieldLabel.Text = 'I_{2D}';
            app.I_2DEditField = uieditfield(app.MeasurementConfigurationPanel, 'text');
            app.I_2DEditField.Value = 'LI Demod 1 X (A)';
            app.OpenRackButton = uibutton(app.MeasurementConfigurationPanel, 'push');
            app.OpenRackButton.Text = 'Open Rack';
            % Show the figure after all components are created
            app.sSETControl.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPMUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.sSETControl)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.sSETControl)
        end
    end
end