classdef Z_CBO_Spectroscopy_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZCBOSpectroscopyUIFigure       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        ZStartumEditFieldLabel         matlab.ui.control.Label
        ZStartumEditField              matlab.ui.control.NumericEditField
        ZEndumEditFieldLabel           matlab.ui.control.Label
        ZEndumEditField                matlab.ui.control.NumericEditField
        TextArea                       matlab.ui.control.TextArea
        BasenameEditFieldLabel         matlab.ui.control.Label
        BasenameEditField              matlab.ui.control.EditField
        NumberofPointsZEditFieldLabel  matlab.ui.control.Label
        NumberofPointsZEditField       matlab.ui.control.NumericEditField
        InprogressLampLabel            matlab.ui.control.Label
        InprogressLamp                 matlab.ui.control.Lamp
        UpdatePlotButton               matlab.ui.control.Button
        OpenVIButton                   matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        ABORTButton                    matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        ZAnalysisButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp; % SPMUI
        vi; % Z cbo spectroscopy.vi
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SPMUI)
            
            app.CallingApp = SPMUI;
            index = app.CallingApp.Nanonis.findvi("Z cbo spectroscopy.vi");
            app.vi = app.CallingApp.Nanonis.vilist(index).viobj;

        end

        % Close request function: ZCBOSpectroscopyUIFigure
        function ZCBOSpectroscopyUIFigureCloseRequest(app, event)
        
            app.CallingApp.OpenExperimentPanelButton.Enable = 'on';  
            app.CallingApp.InProgressLamp.Color = 'g';
            delete(app)
            
        end

        % Value changed function: ZStartumEditField
        function ZStartumEditFieldValueChanged(app, event)
            value = app.ZStartumEditField.Value;
            app.vi.SetControlValue('Z Start', value*1E-6);
        end

        % Value changed function: ZEndumEditField
        function ZEndumEditFieldValueChanged(app, event)
            value = app.ZEndumEditField.Value;
            app.vi.SetControlValue('Z End', value*1E-6);
        end

        % Value changed function: NumberofPointsZEditField
        function NumberofPointsZEditFieldValueChanged(app, event)
            value = app.NumberofPointsZEditField.Value;
            app.vi.SetControlValue('Number of Points Z', value);
        end

        % Value changed function: BasenameEditField
        function BasenameEditFieldValueChanged(app, event)
            value = app.BasenameEditField.Value;
            app.vi.SetControlValue('Save basename (empty= no change)', value);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.InprogressLamp.Color = 'r';
            app.vi.Run;
            
            while(length(dir([session,'\',basename,'*.dat'])) < app.NumberofPointsZEditField.Value)
                ax1 = app.UIAxes;
                ax2 = app.UIAxes_2;
                AppBSliveplot(app.ZStartumEditField.Value, -app.ZEndumEditField.Value, ax1, ax2, app.CallingApp.Session, app.BasenameEditField.Value);
                view(ax1, 2); view(ax2, 2);
                pause(30);  % Update every 30s
            end
            app.InprogressLamp.Color = 'k';
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            ax1 = app.UIAxes;
            ax2 = app.UIAxes_2;
            cla(ax1); cla(ax2);
            AppBSliveplot(app.ZStartumEditField.Value, -app.ZEndumEditField.Value, ax1, ax2, app.CallingApp.Session, app.BasenameEditField.Value);
            view(ax1, 2); view(ax2, 2);
        end

        % Button pushed function: OpenVIButton
        function OpenVIButtonPushed(app, event)
            winopen 'C:\Users\STM\Documents\LabVIEW Data\Nanonis PI\Z cbo spectroscopy.vi';
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            app.vi.Abort;
        end

        % Button pushed function: ZAnalysisButton
        function ZAnalysisButtonPushed(app, event)
            Z_analysis(app.CallingApp.Session, app.BasenameEditField.Value, app.ZStartumEditField.Value, app.ZEndumEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZCBOSpectroscopyUIFigure = uifigure('Visible', 'off');
            app.ZCBOSpectroscopyUIFigure.Name = 'Z CBO Spectroscopy';
            app.ZCBOSpectroscopyUIFigure.CloseRequestFcn = createCallbackFcn(app, @ZCBOSpectroscopyUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.ZCBOSpectroscopyUIFigure);
            title(app.UIAxes, 'Z CBO Spectroscopy - DC')
            xlabel(app.UIAxes, 'Gate/V')
            ylabel(app.UIAxes, 'Z/um')
            app.ZStartumEditFieldLabel = uilabel(app.ZCBOSpectroscopyUIFigure);
            app.ZStartumEditFieldLabel.Text = 'Z Start/um';
            app.ZStartumEditField = uieditfield(app.ZCBOSpectroscopyUIFigure, 'numeric');
            app.ZStartumEditField.ValueChangedFcn = createCallbackFcn(app, @ZStartumEditFieldValueChanged, true);
            app.ZEndumEditFieldLabel = uilabel(app.ZCBOSpectroscopyUIFigure);
            app.ZEndumEditFieldLabel.Text = 'Z End/um';
            app.ZEndumEditField = uieditfield(app.ZCBOSpectroscopyUIFigure, 'numeric');
            app.ZEndumEditField.ValueChangedFcn = createCallbackFcn(app, @ZEndumEditFieldValueChanged, true);
            app.TextArea = uitextarea(app.ZCBOSpectroscopyUIFigure);
            app.TextArea.Value = {'NOTE'; 'Negative means CLOSER'; 'Please mind the unit (um)'; 'Please check the Z LIMIT in Nanonis Z controller module'};
            app.BasenameEditFieldLabel = uilabel(app.ZCBOSpectroscopyUIFigure);
            app.BasenameEditFieldLabel.Text = 'Basename';
            app.BasenameEditField = uieditfield(app.ZCBOSpectroscopyUIFigure, 'text');
            app.BasenameEditField.ValueChangedFcn = createCallbackFcn(app, @BasenameEditFieldValueChanged, true);
            app.NumberofPointsZEditFieldLabel = uilabel(app.ZCBOSpectroscopyUIFigure);
            app.NumberofPointsZEditFieldLabel.Text = 'Number of Points Z';
            app.NumberofPointsZEditField = uieditfield(app.ZCBOSpectroscopyUIFigure, 'numeric');
            app.NumberofPointsZEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPointsZEditFieldValueChanged, true);
            app.InprogressLampLabel = uilabel(app.ZCBOSpectroscopyUIFigure);
            app.InprogressLampLabel.Text = 'In progress';
            app.InprogressLamp = uilamp(app.ZCBOSpectroscopyUIFigure);
            app.UpdatePlotButton = uibutton(app.ZCBOSpectroscopyUIFigure, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.OpenVIButton = uibutton(app.ZCBOSpectroscopyUIFigure, 'push');
            app.OpenVIButton.ButtonPushedFcn = createCallbackFcn(app, @OpenVIButtonPushed, true);
            app.OpenVIButton.Text = 'Open VI';
            app.StartButton = uibutton(app.ZCBOSpectroscopyUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ABORTButton = uibutton(app.ZCBOSpectroscopyUIFigure, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Text = 'ABORT';
            app.UIAxes_2 = uiaxes(app.ZCBOSpectroscopyUIFigure);
            title(app.UIAxes_2, 'Z CBO Spectroscopy - AC')
            xlabel(app.UIAxes_2, 'Gate/V')
            ylabel(app.UIAxes_2, 'Z/um')
            app.ZAnalysisButton = uibutton(app.ZCBOSpectroscopyUIFigure, 'push');
            app.ZAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ZAnalysisButtonPushed, true);
            app.ZAnalysisButton.Text = 'Z Analysis';
            % Show the figure after all components are created
            app.ZCBOSpectroscopyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Z_CBO_Spectroscopy_UI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZCBOSpectroscopyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZCBOSpectroscopyUIFigure)
        end
    end
end